classdef GIU < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RegistrodemacronutrientesdiariosLabel  matlab.ui.control.Label
        AlimentosconsumidosendatosanmalosLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        GrasasEditField              matlab.ui.control.NumericEditField
        GrasasEditFieldLabel         matlab.ui.control.Label
        CarbohidratosEditField       matlab.ui.control.NumericEditField
        CarbohidratosEditFieldLabel  matlab.ui.control.Label
        ProteinasEditField           matlab.ui.control.NumericEditField
        ProteinasEditFieldLabel      matlab.ui.control.Label
        GlucosadiabticoButton        matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        GlucosaNodiabticoButton      matlab.ui.control.Button
        EdadEditFieldLabel           matlab.ui.control.Label
        EdadEditField                matlab.ui.control.NumericEditField
        NuevoregistroButton          matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        GlucosaavgLabel              matlab.ui.control.Label
        ExtrapolarButton             matlab.ui.control.Button
        HbA1cLabel                   matlab.ui.control.Label
        RegistrarButton              matlab.ui.control.Button
        GlucometraEditField          matlab.ui.control.NumericEditField
        GlucometraEditFieldLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data;
       glucoseData;
    end
    
    methods (Access = private)
           
    end
    
    methods (Access = public)
        
        function  Extrapolar(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("Data.mat","Data")
            app.Data = Data;
            Extrapolar(app);
            app.glucoseData = table;
           app.glucoseData = table([], [], [], [], 'VariableNames', ...
        {'Glucose', 'Carbohydrates', 'Fats', 'Proteins'});
    setappdata(0, 'glucoseData', app.glucoseData);
        end

        % Callback function
        function ButtonPushed(app, event)
           
        end

        % Button pushed function: RegistrarButton
        function RegistrarButtonPushed2(app, event)

    gluc = app.GlucometraEditField.Value;
    carbs = app.CarbohidratosEditField.Value;
    fats = app.GrasasEditField.Value;
    proteins = app.ProteinasEditField.Value;

    app.glucoseData = getappdata(0, 'glucoseData');

    newRow = {gluc, carbs, fats, proteins};
    
    app.glucoseData = [app.glucoseData; newRow];
    
    setappdata(0, 'glucoseData', app.glucoseData);
    
    glucoseValues = app.glucoseData.Glucose;
    
    x = linspace(1, length(glucoseValues), length(glucoseValues));
    
    cla(app.UIAxes);
    inRangeIdx = glucoseValues >= 100 & glucoseValues <= 125;
    outRangeIdx = ~inRangeIdx;
   
    scatter(app.UIAxes, x(inRangeIdx), glucoseValues(inRangeIdx), 'MarkerFaceColor', 'b', 'DisplayName', 'Dentro del rango');
    hold(app.UIAxes, 'on');
    scatter(app.UIAxes, x(outRangeIdx), glucoseValues(outRangeIdx), 'MarkerFaceColor', 'r', 'DisplayName', 'Fuera del rango');
    plot(app.UIAxes, x, glucoseValues, 'LineWidth', 2, 'Color', 'k', 'DisplayName', 'Glucosa');
    
    avg = mean(glucoseValues);
    app.Label_2.Text = sprintf('%.2f', avg);
    rango = sum(inRangeIdx);
    tiempor = rango / length(glucoseValues);
    app.Label.Text = sprintf('%.2f', tiempor);
    if any(outRangeIdx)
        outOfRangeFoods = arrayfun(@(i) ...
            sprintf('Carbohidratos: %.1f, Grasas: %.1f, Proteínas: %.1f', ...
            app.glucoseData.Carbohydrates(i), app.glucoseData.Fats(i), app.glucoseData.Proteins(i)), ...
            find(outRangeIdx), 'UniformOutput', false);
        outOfRangeFoodsText = strjoin(outOfRangeFoods, '\n');
        app.Label_3.Text= outOfRangeFoodsText;
    else
        app.Label_3.Text = 'No hay datos fuera de rango.';
    end
    
    legend(app.UIAxes);
    
    hold(app.UIAxes, 'off');

        end

        % Button pushed function: ExtrapolarButton
        function ExtrapolarButtonPushed(app, event)
            
     
 app.glucoseData = getappdata(0, 'glucoseData');
    
    if isempty(app.glucoseData) || height(app.glucoseData) == 0
        return; 
    end
    
    glucoseValues = app.glucoseData.Glucose;
    x = linspace(1, height(app.glucoseData), height(app.glucoseData));
    
    glucoseValues = app.glucoseData.Glucose;
    p = polyfit(x, glucoseValues, 1);
    future_x = (height(app.glucoseData) + 1):(height(app.glucoseData) + 30);
    
    future_y = polyval(p, future_x);
    x_combined = [x, future_x];
    y_combined = [glucoseValues; future_y'];
    
    
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x_combined, y_combined, '--g', 'LineWidth', 2, 'DisplayName', 'Extrapolación');
    legend(app.UIAxes);
    hold(app.UIAxes, 'off');
        end

        % Button pushed function: NuevoregistroButton
        function NuevoregistroButtonPushed(app, event)
 
  setappdata(0, 'glucoseData', []);
  app.glucoseData = [];
  cla(app.UIAxes);
  app.Label.Text = '0.00';
            
        end

        % Button pushed function: GlucosaNodiabticoButton
        function GlucosaNodiabticoButtonPushed(app, event)
filteredBloodGlucose = []; % Inicializar arreglo para almacenar los datos filtrados
rangeColor = ''; % Inicializar variable para almacenar el color del rango

for i = 1:1:329
    if app.EdadEditField.Value > 0 && app.EdadEditField.Value <= 10
        if app.Data.Age(i) > 0 && app.Data.Age(i) <= 10
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'g';
        end
    elseif app.EdadEditField.Value > 10 && app.EdadEditField.Value <= 20
        if app.Data.Age(i) > 10 && app.Data.Age(i) <= 20
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'b';
        end
    elseif app.EdadEditField.Value > 20 && app.EdadEditField.Value <= 40
        if app.Data.Age(i) > 20 && app.Data.Age(i) <= 40
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'r';
        end
    elseif app.EdadEditField.Value > 40 && app.EdadEditField.Value <= 60
        if app.Data.Age(i) > 40 && app.Data.Age(i) <= 60
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'm';
        end
    elseif app.EdadEditField.Value > 60 && app.EdadEditField.Value <= 80
        if app.Data.Age(i) > 60 && app.Data.Age(i) <= 80
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'c';
            
        end
    elseif app.EdadEditField.Value > 80 && app.EdadEditField.Value <= 100
        if app.Data.Age(i) > 80 && app.Data.Age(i) <= 100
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'c';
    end
    end
end

if ~isempty(filteredBloodGlucose)
    x = linspace(1, length(filteredBloodGlucose), length(filteredBloodGlucose));
    scatter(app.UIAxes, x, filteredBloodGlucose, 'MarkerFaceColor', rangeColor);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, filteredBloodGlucose, 'LineWidth', 2, 'Color', rangeColor, 'DisplayName', 'Glucosa');
    hold(app.UIAxes, 'off');
    xlabel(app.UIAxes, 'Edad (años)');
    ylabel(app.UIAxes, 'Nivel de glucosa en sangre');
    title(app.UIAxes, 'Niveles de glucosa en sangre por rango de edad');
else
    cla(app.UIAxes); % Limpia el gráfico
    title(app.UIAxes, 'No hay datos disponibles para la edad especificada');
end


        end

        % Callback function: not associated with a component
        function ButtonPushed2(app, event)
        subplot(1, 2, 1);
       
        end

        % Button pushed function: GlucosadiabticoButton
        function GlucosadiabticoButtonPushed(app, event)
            filteredBloodGlucose = []; % Inicializar arreglo para almacenar los datos filtrados
rangeColor = ''; % Inicializar variable para almacenar el color del rango

for i = 330:1:length(app.Data.Age)
    if app.EdadEditField.Value > 0 && app.EdadEditField.Value <= 10
        if app.Data.Age(i) > 0 && app.Data.Age(i) <= 10
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'g';
        end
    elseif app.EdadEditField.Value > 10 && app.EdadEditField.Value <= 20
        if app.Data.Age(i) > 10 && app.Data.Age(i) <= 20
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'b';
        end
    elseif app.EdadEditField.Value > 20 && app.EdadEditField.Value <= 40
        if app.Data.Age(i) > 20 && app.Data.Age(i) <= 40
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'r';
        end
    elseif app.EdadEditField.Value > 40 && app.EdadEditField.Value <= 60
        if app.Data.Age(i) > 40 && app.Data.Age(i) <= 60
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'm';
        end
    elseif app.EdadEditField.Value > 60 && app.EdadEditField.Value <= 80
        if app.Data.Age(i) > 60 && app.Data.Age(i) <= 80
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'c';
            
        end
    elseif app.EdadEditField.Value > 80 && app.EdadEditField.Value <= 100
        if app.Data.Age(i) > 80 && app.Data.Age(i) <= 100
            filteredBloodGlucose(end + 1) = app.Data.BloodGlucoseLevel_BGL_(i);
            rangeColor = 'c';
    end
    end
end

if ~isempty(filteredBloodGlucose)
    x = linspace(1, length(filteredBloodGlucose), length(filteredBloodGlucose));
    scatter(app.UIAxes, x, filteredBloodGlucose, 'MarkerFaceColor', rangeColor);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, filteredBloodGlucose, 'LineWidth', 2, 'Color', rangeColor, 'DisplayName', 'Glucosa');
    hold(app.UIAxes, 'off');
    xlabel(app.UIAxes, 'Edad (años)');
    ylabel(app.UIAxes, 'Nivel de glucosa en sangre');
    title(app.UIAxes, 'Niveles de glucosa en sangre por rango de edad');
else
    cla(app.UIAxes); % Limpia el gráfico
    title(app.UIAxes, 'No hay datos disponibles para la edad especificada');
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Glucometría ')
            xlabel(app.UIAxes, 'Tiempo (días)')
            ylabel(app.UIAxes, 'Glucosa mg/dL')
            zlabel(app.UIAxes, 'Z')
            app.GlucometraEditFieldLabel = uilabel(app.UIFigure);
            app.GlucometraEditFieldLabel.Text = 'Glucometría';
            app.GlucometraEditField = uieditfield(app.UIFigure, 'numeric');
            app.RegistrarButton = uibutton(app.UIFigure, 'push');
            app.RegistrarButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrarButtonPushed2, true);
            app.RegistrarButton.Text = 'Registrar';
            app.HbA1cLabel = uilabel(app.UIFigure);
            app.HbA1cLabel.Text = 'HbA1c';
            app.ExtrapolarButton = uibutton(app.UIFigure, 'push');
            app.ExtrapolarButton.ButtonPushedFcn = createCallbackFcn(app, @ExtrapolarButtonPushed, true);
            app.ExtrapolarButton.Text = 'Extrapolar';
            app.GlucosaavgLabel = uilabel(app.UIFigure);
            app.GlucosaavgLabel.Text = 'Glucosa avg';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.NuevoregistroButton = uibutton(app.UIFigure, 'push');
            app.NuevoregistroButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoregistroButtonPushed, true);
            app.NuevoregistroButton.Text = 'Nuevo registro';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.GlucosaNodiabticoButton = uibutton(app.UIFigure, 'push');
            app.GlucosaNodiabticoButton.ButtonPushedFcn = createCallbackFcn(app, @GlucosaNodiabticoButtonPushed, true);
            app.GlucosaNodiabticoButton.Text = 'Glucosa No diabético ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.GlucosadiabticoButton = uibutton(app.UIFigure, 'push');
            app.GlucosadiabticoButton.ButtonPushedFcn = createCallbackFcn(app, @GlucosadiabticoButtonPushed, true);
            app.GlucosadiabticoButton.Text = 'Glucosa diabético ';
            app.ProteinasEditFieldLabel = uilabel(app.UIFigure);
            app.ProteinasEditFieldLabel.Text = 'Proteinas';
            app.ProteinasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarbohidratosEditFieldLabel = uilabel(app.UIFigure);
            app.CarbohidratosEditFieldLabel.Text = 'Carbohidratos';
            app.CarbohidratosEditField = uieditfield(app.UIFigure, 'numeric');
            app.GrasasEditFieldLabel = uilabel(app.UIFigure);
            app.GrasasEditFieldLabel.Text = 'Grasas';
            app.GrasasEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.AlimentosconsumidosendatosanmalosLabel = uilabel(app.UIFigure);
            app.AlimentosconsumidosendatosanmalosLabel.Text = 'Alimentos consumidos en datos anómalos';
            app.RegistrodemacronutrientesdiariosLabel = uilabel(app.UIFigure);
            app.RegistrodemacronutrientesdiariosLabel.Text = 'Registro de macronutrientes diarios ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GIU

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end